classdef CODA_TF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreProcessingTab                matlab.ui.container.Tab
        Panel_timesig                   matlab.ui.container.Panel
        UIAxes_freqsig                  matlab.ui.control.UIAxes
        UIAxes_timesig                  matlab.ui.control.UIAxes
        LoadFilePanel                   matlab.ui.container.Panel
        LoadAudioFileButton             matlab.ui.control.Button
        PreprocessingSettingsPanel      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        Preproc_ExportPlotsButton       matlab.ui.control.Button
        ResetAllButton                  matlab.ui.control.Button
        MagRespAxisScalingPanel         matlab.ui.container.Panel
        MagnitudeUnitsSwitch            matlab.ui.control.Switch
        MagnitudeUnitsSwitchLabel       matlab.ui.control.Label
        preproc_FAxisScaleSwitch        matlab.ui.control.Switch
        FrequencyAxisScaleSwitchLabel   matlab.ui.control.Label
        NormPanel                       matlab.ui.container.Panel
        preproc_NormalizeButton         matlab.ui.control.Button
        ResampPanel                     matlab.ui.container.Panel
        ResampleButton                  matlab.ui.control.Button
        UpperFreqLimitHzEditField       matlab.ui.control.NumericEditField
        UpperFreqLimitHzEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        TrimPanel                       matlab.ui.container.Panel
        EndTimesEditField               matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel          matlab.ui.control.Label
        StartTimesEditField             matlab.ui.control.NumericEditField
        StartTimesEditFieldLabel        matlab.ui.control.Label
        TrimSignalButton                matlab.ui.control.Button
        SuperletScalogramTab            matlab.ui.container.Tab
        TimeFrequencyDomainSignalPlotPanel_3  matlab.ui.container.Panel
        UIAxes_SLTScalo                 matlab.ui.control.UIAxes
        TimeFrequencyAnalysisSettingsPanel_3  matlab.ui.container.Panel
        PlotSettingsPanel               matlab.ui.container.Panel
        SLT_SigQuantityUnitsButtonGroup  matlab.ui.container.ButtonGroup
        LogPowerdBWButton               matlab.ui.control.ToggleButton
        PowerWButton                    matlab.ui.control.ToggleButton
        LogMagnitudedBButton            matlab.ui.control.ToggleButton
        MagnitudearbButton              matlab.ui.control.ToggleButton
        SLT_NormalizeButton             matlab.ui.control.StateButton
        SLT_FAxisScalingSwitch          matlab.ui.control.Switch
        FrequencyAxisScalingSwitchLabel  matlab.ui.control.Label
        SLT_ExportPlotButton            matlab.ui.control.Button
        AlgorithmSettingsPanel          matlab.ui.container.Panel
        PlotScalogramButton             matlab.ui.control.Button
        CyclesSpinner                   matlab.ui.control.Spinner
        InitialNumberofSuperletCyclesSpinnerLabel  matlab.ui.control.Label
        fresSpinner                     matlab.ui.control.Spinner
        FrequencyResolutionHzSpinnerLabel  matlab.ui.control.Label
        HighestFSpinner                 matlab.ui.control.Spinner
        HighestFrequencyOfInterestHzSpinnerLabel  matlab.ui.control.Label
        LowestFSpinner                  matlab.ui.control.Spinner
        LowestFrequencyOfInterestHzSpinnerLabel  matlab.ui.control.Label
        SuperresolutionModeSwitch       matlab.ui.control.Switch
        SuperresolutionModeSwitchLabel  matlab.ui.control.Label
        Upper_order_EditField           matlab.ui.control.NumericEditField
        UpperSuperresolutionorderEditFieldLabel  matlab.ui.control.Label
        Lower_order_EditField           matlab.ui.control.NumericEditField
        LowerSuperresolutionorderEditFieldLabel  matlab.ui.control.Label
        STFTSpectrogramTab              matlab.ui.container.Tab
        TimeFrequencyDomainSignalPlotPanel  matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        TimeFrequencyAnalysisSettingsPanel  matlab.ui.container.Panel
    end


    properties (Access = private)
        file            % File name of the audio to load
        path            % Path to the audio to load
        signal          % Original audio signal
        working_signal  % Signal to analyse (with preprocessing modifications)
        original_fs     % The sampling frequency of the original audio signal
        working_fs      % The Fs of working_signal
        last_fs          % The previous setting of the sample rate edit box
        t_vec           % The time vector of the original signal
        working_t_vec   % The time vector for the working signal
        t_start         % Start time for trimmed audio
        t_end           % End time for trimmed audio
        t_start_idx     % The index for the start of the trimmed signal
        t_end_idx       % The index for the end of the trimmed signal
        trimmed_flag    % A flag that indicates to other functions whether the signal has been trimmed
        resampd_flag    % A flag that indicates to other functions whether the signal has been resampled
        normed_flag     % A flag that indicates to other functions whether the signal has been normalised
        fmin            % Lowest frequency of interest for SLT transform
        fmax            % Highest frequency of interest for SLT transform
        fres            % Target frequency resolution for SLT transform
        cycles          % Initial number of superlet cycles for SLT transform
        order           % Interval of superresolution orders for SLT transform
        mult            % Superresolution mode SLT transform
        SLT_units       % Sets signal quantity units for the SLT TFR plot
        SLT_fscale      % Sets the frequecny axis scaling for the SLT TFR plot
        swlet           % Raw result of the superlet transform
        sltplot         % Post-Processed result if the superlet transform
        SLT_normed_flag % A flag that indicates to other functions whether the SLT TFR has been normalised
        preproc_fscale  % The frequency axis scaling for the magnitude response plot
        SLT_f_vec       % The frequency axis values for the SLT TFR Plot
        colorbar_label  % Dynamic Label for colorbar in TFR plots
        SLT_fig         % The Superlet plot
        preprocunit_value
        preproc_f_vec
        f_signal
        f_signal_unit_converted
        f_signal_norm

    end

    methods (Access = private)

        % PreProcesing Tab - Redraw Time Domain Plot
        function redrawTimeplot(app, t, s)
                % Update plot
                plot(app.UIAxes_timesig, t, s, 'Tag', 'Timedata');

                % Set plot axis limits
                if app.normed_flag == 0
                    app.UIAxes_timesig.YLim = [min(s), max(s)];
                elseif app.normed_flag == 1
                    app.UIAxes_timesig.YLim = [-1.1, 1.1];
                end
                drawnow;
                figure(app.UIFigure)
        end

        % PreProcesing Tab - Redraw Frequency Domain Plot
        function redrawMAGplot(app, f)
            
            % If signal has been resampled, use new sample rate
            if app.resampd_flag == 0
                fs = app.original_fs;
            elseif app.resampd_flag == 1
                fs = app.working_fs;
            end

            % Generate frequency vector
            app.preproc_f_vec =  linspace(1, fs/2, length(f));

            % Generate plot
            plot(app.UIAxes_freqsig, app.preproc_f_vec, f)

            % Set plot x-axis limits
            app.UIAxes_freqsig.XLim = [1, fs/2];
            
            % Set plot y-axis limits
            if app.normed_flag == 0 && app.preprocunit_value == 0
                app.UIAxes_freqsig.YLim = [min(f), max(f)];            
            elseif app.normed_flag == 0 && app.preprocunit_value == 1
                app.UIAxes_freqsig.YLim = [0, max(f)];
            elseif app.normed_flag == 1 && app.preprocunit_value == 0
                app.UIAxes_freqsig.YLim = [0, 1];
            elseif app.normed_flag == 1 && app.preprocunit_value == 1
                app.UIAxes_freqsig.YLim = [min(f), 0];

            end
        end

        % PreProcessing Tab - Initialise Editable Field Default Values
        function preprocINIT(app)
                % Set fs limits and init values
                app.SampleRateHzEditField.Limits = [50, app.original_fs];
                app.SampleRateHzEditField.Value = app.original_fs;
                app.UpperFreqLimitHzEditField.Value = app.original_fs / 2;
                app.fmax = app.original_fs / 2;

                % Set time limits and init values
                app.StartTimesEditField.Limits = [app.t_vec(1), app.t_vec(end-1)];
                app.StartTimesEditField.Value = app.t_vec(1);
                app.EndTimesEditField.Limits = [app.t_vec(2), app.t_vec(end)];
                app.EndTimesEditField.Value = app.t_vec(end);
                app.t_start_idx = 1;
                app.t_end_idx = length(app.signal);

                % Reset mag units to linear
                app.MagnitudeUnitsSwitch.Value = 'Linear';

                % Set logical operatr for units to 0/linear
                app.preprocunit_value = strcmp(app.MagnitudeUnitsSwitch.Value, 'Log (dB)');  
                
                % Reset fscaling to linear
                app.preproc_FAxisScaleSwitch.Value = 'Lin';
        end
        
        % PreProcessing Tab - Resample Signal
        function resamp(app)
            if app.trimmed_flag == 0 && app.resampd_flag == 0
                app.working_signal = easySRC(app.signal, app.original_fs, app.working_fs, app.working_fs/2);
                samps = length(app.working_signal);
                duration = samps / app.working_fs;
                app.working_t_vec = linspace(0, duration, samps);
            elseif app.trimmed_flag == 1 && app.resampd_flag == 0
                app.working_signal = easySRC(app.working_signal, app.original_fs, app.working_fs, app.working_fs/2);
                samps = length(app.working_signal);
                duration = samps / app.working_fs;
                app.working_t_vec = linspace(0, duration, samps);
            elseif app.trimmed_flag == 1 && app.resampd_flag == 1
                app.working_signal = easySRC(app.working_signal, app.original_fs, app.working_fs, app.working_fs/2);
                samps = length(app.working_signal);
                duration = samps / app.working_fs;
                app.working_t_vec = linspace(0, duration, samps);
            elseif app.trimmed_flag == 0 && app.resampd_flag == 1
                app.working_signal = easySRC(app.signal, app.original_fs, app.working_fs, app.working_fs/2);
                samps = length(app.working_signal);
                duration = samps / app.working_fs;
                app.working_t_vec = linspace(0, duration, samps);
            end
            % Set Flag to show that signal has been resampled
            app.resampd_flag = 1;

            % Update fmax
            app.fmax = app.working_fs / 2;
        end
        
        % PreProcessing Tab - Compute Magnitude Spectrum
        function computemagspect(app, x)
            % Compute fft of signal    
            nfft = length(x);
            app.f_signal = fft(x, nfft);
            
            % Take the absolute value and discard the negative frequencies
            app.f_signal = abs(app.f_signal(1:nfft/2+1));
        end

        function preproc_norm(app)
            % Check which signal to use as time domain input
            if app.trimmed_flag == 0 && app.resampd_flag == 0
                x = app.signal;
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                x = app.working_signal;
            end

            % Do normalisation of time domain input
            app.working_signal = x ./ abs(max(x));

            % Do normalisation of frequency domain input
            app.f_signal_norm = app.f_signal ./ max(app.f_signal);

            % Set flag to indicate signal has been normed
            app.normed_flag = 1;
        end
        
        % Preprocessing Tab - Update Mag plot Frequency Axis
        function preprocfaxisswitch(app, value)
            % Convert value to type: logical: 1 for Lin, 0 for log.
            value = strcmp(value, 'Lin');

            % Render updated plot
            if value == 1 % linear
                app.UIAxes_freqsig.XScale = 'linear';
                app.UIAxes_freqsig.XLim = [1, app.fmax];
            elseif value == 0 % Log
                app.UIAxes_freqsig.XScale = 'log';
                app.UIAxes_freqsig.XLim = [1, app.fmax];
            end
        end

        % PreProcessing Tab - Update Magnitude units
        function preproc_mag_unitupdate(app)
            if app.normed_flag == 0
                x = app.f_signal;
            elseif app.normed_flag == 1
                x = app.f_signal_norm;
            end

            % Unit conversion
            if app.preprocunit_value == 1
                app.f_signal_unit_converted = 20*log10(x);
                app.UIAxes_freqsig.YLabel.String = 'Magnitude (dB)';
            elseif app.preprocunit_value == 0
                app.f_signal_unit_converted = x;
                app.UIAxes_freqsig.YLabel.String = 'Magnitude (arbitrary)';
            end            
        end

        % SLT Tab - Redraw TFR Plot
        function redrawSLTplot(app, f, t, mat)
            % Set colorbar label
            if app.SLT_normed_flag == 0
                app.colorbar_label = app.SLT_units;
            elseif app.SLT_normed_flag == 1
                app.colorbar_label = [app.SLT_units(1:end-1), ', normalised)'];
            end

            % Generate plot
            surf(app.UIAxes_SLTScalo, f, t, mat', EdgeColor = 'None');
            app.UIAxes_SLTScalo.XLim = [app.fmin, app.fmax];
            app.UIAxes_SLTScalo.YLim = [app.working_t_vec(1), app.working_t_vec(end)];
            app.UIAxes_SLTScalo.XDir = 'reverse';
            app.UIAxes_SLTScalo.YDir = 'normal';
            app.UIAxes_SLTScalo.View = [90 90];
            c = colorbar(app.UIAxes_SLTScalo);
            c.Label.String = app.colorbar_label;
            drawnow;
            figure(app.UIFigure)
            app.UIAxes_SLTScalo.Toolbar.Visible = 'on';
        end

        % SLT Tab - Update Signal Quantity Units 
        function dataOUT = SLTunitconvert(~, units, dataIN)
                if strcmp(units,'Magnitude (arb)') == 1
                    dataOUT = dataIN;
                elseif strcmp(units,'Log Magnitude (dB)') == 1
                    dataOUT = 20*log10(dataIN);
                elseif strcmp(units,'Power (W)') == 1
                    dataOUT = dataIN .^2;
                elseif strcmp(units,'Log Power (dBW)') == 1
                    dataOUT = 10*log10(dataIN .^2);
                end
        end
        
        % SLT Tab - Normalise TFR to max = 1
        function SLTnormalise(app)
            if app.SLT_NormalizeButton.Value == 1 && app.SLT_normed_flag == 0
                % Normalize the TFR
                app.sltplot = app.sltplot ./ max(app.sltplot, [], 'all');
                app.SLT_normed_flag = 1;
            elseif app.SLT_NormalizeButton.Value == 0 && app.SLT_normed_flag == 1
                % Plot the un-normalised version
                app.sltplot = app.swlet;
                app.SLT_normed_flag = 0;
            elseif app.SLT_NormalizeButton.Value == 0 && app.SLT_normed_flag == 0
                app.sltplot = app.swlet;
                app.SLT_normed_flag = 0;
            elseif app.SLT_NormalizeButton.Value == 1 && app.SLT_normed_flag == 1
                app.sltplot = app.sltplot;
                app.SLT_normed_flag = 1;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAudioFileButton
        function LoadAudioFileButtonPushed(app, event)
            if ispc
                home = getenv('USERPROFILE');
            else
                home = getenv('HOME');
            end

            % User selects the file to load
            [app.file, app.path] = uigetfile('.wav', 'Load WAV file:', home);

            if isprop(app, 'file') == 1 && isprop(app, 'path') == 1
                % Load the audio file
                [app.signal, app.original_fs] = audioread(fullfile(app.path, app.file));

                % Set up time vector
                samps = length(app.signal);
                duration = samps / app.original_fs;
                app.t_vec = linspace(0, duration, samps);
                
                % Initialise time and fs default values
                preprocINIT(app)

                % If signal has an odd number of samples, drop the last one
                if mod(length(app.signal), 2) == 0
                    % do nothing
                elseif mod(length(app.signal), 2) ~= 0
                    app.signal = app.signal(1:end-1);
                end

                % Set flags to say that the signal has NOT yet been modified
                app.trimmed_flag = 0;
                app.resampd_flag = 0;
                app.normed_flag = 0;

                % Plot time domain waveform
                redrawTimeplot(app, app.t_vec, app.signal)

                % Compute Mag Spectrum
                computemagspect(app, app.signal)

                % Plot Magnitude Spectrum
                redrawMAGplot(app, app.f_signal)

                % Set flags to say that the signal has NOT been trimmed or
                % resampled

            end
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            if isprop(app, 'file') == 1 && isprop(app, 'path') == 1
                % Load the audio file
                [app.signal, app.original_fs] = audioread(fullfile(app.path, app.file));

            elseif isprop(app, 'file') ~= 1 && isprop(app, 'path') ~= 1
                if ispc
                    home = getenv('USERPROFILE');
                else
                    home = getenv('HOME');
                end
                % User selects the file to load
                [app.file, app.path] = uigetfile('.wav', 'Load WAV file:', home);
                % Load the audio file
                [app.signal, app.original_fs] = audioread(fullfile(app.path, app.file));
            end

            % Set up time vector
            samps = length(app.signal);
            duration = samps / app.original_fs;
            app.t_vec = linspace(0, duration, samps);

            % If signal has an odd number of samples, drop the last one
            if mod(length(app.signal), 2) == 0
                % do nothing
            elseif mod(length(app.signal), 2) ~= 0
                app.signal = app.signal(1:end-1);
            end

            % Set flags to say that the signal has NOT yet been modified
            app.trimmed_flag = 0;
            app.resampd_flag = 0;
            app.normed_flag = 0;

            % Initialise time and fs default values
            preprocINIT(app)

            % Update units 
            preproc_mag_unitupdate(app)

            % Redraw magnitude spectrum plot
            redrawMAGplot(app, app.f_signal_unit_converted)

            % Plot time domain waveform
            redrawTimeplot(app, app.t_vec, app.signal)

            % Compute magnitude spectrum
            computemagspect(app, app.signal)

            % Plot Magnitude Spectrum
            redrawMAGplot(app, app.f_signal)

            % Re-Enable start and end time fields & trim buttons
            app.StartTimesEditField.Editable = 'on';
            app.EndTimesEditField.Editable = "on";
            app.TrimSignalButton.Enable = "on";

            % Re-Enable sample rate edit field
            app.SampleRateHzEditField.Editable = "on";
            app.ResampleButton.Enable = "on";

            % Re-Enable normalize button
            app.preproc_NormalizeButton.Enable = 'on';
        end

        % Value changed function: StartTimesEditField
        function StartTimesEditFieldValueChanged(app, event)
            % Get new values
            app.t_start = app.StartTimesEditField.Value;

            if app.trimmed_flag == 0 && app.resampd_flag == 0
                % Get indices from values
                [~, app.t_start_idx] = min(abs(app.t_vec - app.t_start));
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                % Get indices from values
                [~, app.t_start_idx] = min(abs(app.working_t_vec - app.t_start));
            end
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            % Get new value
            app.t_end = app.EndTimesEditField.Value;

            if app.trimmed_flag == 0 && app.resampd_flag == 0
                % Get indices from values
                [~, app.t_end_idx] = min(abs(app.t_vec - app.t_end));
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                % Get indices from values
                [~, app.t_end_idx] = min(abs(app.working_t_vec - app.t_end));
            end
        end

        % Button pushed function: TrimSignalButton
        function TrimSignalButtonPushed(app, event)
            % Trim signal accordingly
            if app.trimmed_flag == 0 && app.resampd_flag == 0
                app.working_signal = app.signal(app.t_start_idx:app.t_end_idx);
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                app.working_signal = app.working_signal(app.t_start_idx:app.t_end_idx);
            end

            % Set up time vector
            workingsig_samps = length(app.working_signal);
            workingsig_duration = workingsig_samps / app.original_fs;
            app.working_t_vec = linspace(0, workingsig_duration, workingsig_samps);

            % Set flag to say that the signal has been trimmed
            app.trimmed_flag = 1;

            % Disable edit start and end time fields & trim button
            app.StartTimesEditField.Editable = 'off';
            app.EndTimesEditField.Editable = "off";
            app.TrimSignalButton.Enable = "off";

            % Update plot with trimmed signal
            redrawTimeplot(app, app.working_t_vec, app.working_signal)

            % Compute Mag Spectrum
            computemagspect(app, app.working_signal)

            % Update magnitude response plot
            redrawMAGplot(app, app.f_signal)
        end

        % Value changed function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanged(app, event)
            % Set new_fs property from UI element
            app.working_fs = app.SampleRateHzEditField.Value;

            % Update fmax field
            app.UpperFreqLimitHzEditField.Value = app.working_fs / 2;

        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            % Resampling progress bar
            wbar = waitbar(0.25,'Please wait, resampling audio...');

            % Resample signal
            resamp(app)

            % Update progress bar
            waitbar(0.50, wbar, 'Please wait, resampling audio...')

            % Compute magnitude spectrum
            computemagspect(app, app.working_signal)

            % Update mag plot units
            preproc_mag_unitupdate(app)
            
            % Update magnitude spectrum plot
            redrawMAGplot(app, app.f_signal)

            % Update progress bar
            waitbar(0.75, wbar, 'Please wait, resampling audio...')
            close(wbar)

            % Set start and end time fields & trim button to read-only
            app.SampleRateHzEditField.Editable = 'off';
            app.ResampleButton.Enable = "off";
        end

        % Button pushed function: preproc_NormalizeButton
        function preproc_NormalizeButtonPushed(app, event)
                % Normalize the signal
                preproc_norm(app)

                % Update unit conversion after norming.
                preproc_mag_unitupdate(app)
                
                % 
                if app.trimmed_flag == 0 && app.resampd_flag == 0
                    t = app.t_vec;
                elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                    t = app.working_t_vec;
                end

                % Update time plot with
                redrawTimeplot(app, t, app.working_signal)

                % Update magnitude spectrum plot
                redrawMAGplot(app, app.f_signal_unit_converted) % f_signal_unit_converted

            app.preproc_NormalizeButton.Enable = 'off';
        end

        % Value changed function: preproc_FAxisScaleSwitch
        function preproc_FAxisScaleSwitchValueChanged(app, event)
            % Get value from UI element
            value = app.preproc_FAxisScaleSwitch.Value;

            preprocfaxisswitch(app, value)
            
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: MagnitudeUnitsSwitch
        function MagnitudeUnitsSwitchValueChanged(app, event)
            % Get value from UI element
            value = app.MagnitudeUnitsSwitch.Value;

            % Convert value to type: logical: 1 for log, 0 for lin
            app.preprocunit_value = strcmp(value, 'Log (dB)');

            % Update units 
            preproc_mag_unitupdate(app)

            % Redraw magnitude spectrum plot
            redrawMAGplot(app, app.f_signal_unit_converted)
        end

        % Button pushed function: Preproc_ExportPlotsButton
        function Preproc_ExportPlotsButtonPushed(app, event)
            savepath = uigetdir(app.path, 'Select Save Directory');
            savename_time = ['TimeDomain_', app.file(1:end-4), '.pdf'];
            savename_freq = ['FreqDomain_', app.file(1:end-4), '.pdf'];
            exportgraphics(app.UIAxes_timesig, fullfile(savepath, savename_time), ContentType='vector')
            exportgraphics(app.UIAxes_freqsig, fullfile(savepath, savename_freq), ContentType='vector')

        end

        % Button down function: SuperletScalogramTab
        function SuperletScalogramTabButtonDown(app, event)
            % Check if signal was modified in pre processing tab:
            b = any([app.trimmed_flag, app.resampd_flag, app.normed_flag], 'all');
            % If signal is unmodified, use original as working_signal:
            if ismember(1, b) == 0
                app.working_signal = app.signal;
                app.working_fs = app.original_fs;
            elseif ismember(1, b) ~= 0
                app.working_signal = app.working_signal;
                app.working_fs = app.working_fs;
            end

            % Init Default SLT Algorithm Settings
            app.LowestFSpinner.Limits = [1, (app.working_fs/2)-1];
            app.LowestFSpinner.Value = 10;
            app.fmin = app.LowestFSpinner.Value;

            app.HighestFSpinner.Limits = [2, app.working_fs/2];
            app.HighestFSpinner.Value = app.working_fs/2;
            app.fmax = app.HighestFSpinner.Value;

            app.fresSpinner.Limits = [0.05, 10];
            app.fresSpinner.Value = 0.1;
            app.fres = app.fresSpinner.Value;

            app.CyclesSpinner.Limits = [1, 10];
            app.CyclesSpinner.Value = 4;
            app.cycles = app.CyclesSpinner.Value;

            app.Upper_order_EditField.Limits = [10, 100];
            app.Upper_order_EditField.Value = 40;

            app.Lower_order_EditField.Limits = [1, 90];
            app.Lower_order_EditField.Value = 10;

            app.order = [app.Lower_order_EditField.Value, app.Upper_order_EditField.Value];

            app.SuperresolutionModeSwitch.Value = 'Multiplicative';
            if strcmp(app.SuperresolutionModeSwitch.Value, 'Multiplicative') == 1
                app.mult = 1;
            elseif strcmp(app.SuperresolutionModeSwitch.Value, 'Additive') == 1
                app.mult = 0;
            end

            % Init default units
            app.SLT_units = app.SLT_SigQuantityUnitsButtonGroup.SelectedObject.Text;

            % Disable plot controls until plot has been generated
            app.SLT_NormalizeButton.Enable = "off";
            app.SLT_SigQuantityUnitsButtonGroup.Enable = "off";
            app.SLT_FAxisScalingSwitch.Enable = "off";
            app.SLT_ExportPlotButton.Enable = "off";

            % Init normalisation flag
            app.SLT_normed_flag = 0;

        end

        % Value changed function: LowestFSpinner
        function LowestFSpinnerValueChanged(app, event)
            app.fmin = app.LowestFSpinner.Value;

        end

        % Value changed function: HighestFSpinner
        function HighestFSpinnerValueChanged(app, event)
            app.fmax = app.HighestFSpinner.Value;

        end

        % Value changed function: fresSpinner
        function fresSpinnerValueChanged(app, event)
            app.fres = app.fresSpinner.Value;

        end

        % Value changed function: CyclesSpinner
        function CyclesSpinnerValueChanged(app, event)
            app.cycles = app.CyclesSpinner.Value;

        end

        % Value changed function: Lower_order_EditField
        function Lower_order_EditFieldValueChanged(app, event)
            app.order(1) = app.Lower_order_EditField.Value;

        end

        % Value changed function: Upper_order_EditField
        function Upper_order_EditFieldValueChanged(app, event)
            app.order(2) = app.Upper_order_EditField.Value;

        end

        % Value changed function: SuperresolutionModeSwitch
        function SuperresolutionModeSwitchValueChanged(app, event)
            if strcmp(app.SuperresolutionModeSwitch.Value, 'Multiplicative') == 1
                app.mult = 1;
            elseif strcmp(app.SuperresolutionModeSwitch.Value, 'Additive') == 1
                app.mult = 0;
            end
        end

        % Button pushed function: PlotScalogramButton
        function PlotScalogramButtonPushed(app, event)
            % Init Progress Bar
            wbar = waitbar(0.25, 'Please wait, computing SLT...');
            pause(0.5)

            % Init counts, Vectors etc.
            n_freqs = (app.fmax - app.fmin) / app.fres;
            app.SLT_f_vec = linspace(app.fmin, app.fmax, n_freqs);

            % Update progress bar
            waitbar(0.50, wbar, 'Please wait, computing SLT...');

            % Compute superlets Transform
            app.swlet = nfaslt(app.working_signal, app.working_fs, ...
                [app.fmin, app.fmax], n_freqs, app.cycles, app.order,...
                app.mult);

            % Update progress bar
            pause(1)
            waitbar(0.75, wbar, 'Please wait, generating plot...');

            % Normalize the TFR
            SLTnormalise(app)

            % Convert Units
            app.sltplot = SLTunitconvert(app, app.SLT_units, app.swlet);

            % Set X Scale for UIAxes object 
            if strcmp(app.SLT_fscale, "Linear") == 1
                app.UIAxes_SLTScalo.XScale = "linear";
            elseif strcmp(app.SLT_fscale, "Logarithmic") == 1
                app.UIAxes_SLTScalo.XScale = "log";
            end

            % Redraw SLT plot
            redrawSLTplot(app, app.SLT_f_vec, app.working_t_vec, app.sltplot)
            
            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)

            % Enable plot controls
            app.SLT_NormalizeButton.Enable = "on";
            app.SLT_SigQuantityUnitsButtonGroup.Enable = "on";
            app.SLT_FAxisScalingSwitch.Enable = "on";
            app.SLT_ExportPlotButton.Enable = "on";
        end

        % Value changed function: SLT_FAxisScalingSwitch
        function SLT_FAxisScalingSwitchValueChanged(app, event)
            app.SLT_fscale = app.SLT_FAxisScalingSwitch.Value;
            % Progress Bar
            wbar = waitbar(0.50, 'Please wait, Updating Plot...');
            pause(0.5)

            % Set X Scale & Ticks for UIAxes object 
            if strcmp(app.SLT_fscale, "Linear") == 1
                app.UIAxes_SLTScalo.XScale = "linear";
%                 ticks = 10:10:app.f_max;
%                 app.UIAxes_SLTScalo.xticks = ticks;
%                 app.UIAxes_SLTScalo.xticklabels = arrayfun(@num2str, ticks, 'UniformOutput', 0);
            elseif strcmp(app.SLT_fscale, "Logarithmic") == 1
                app.UIAxes_SLTScalo.XScale = "log";
%                 ticks = [(1:1:10), (20:10:100), (200:100:1000), (2000:1000:10000), (20000:10000:100000)];
%                 app.UIAxes_SLTScalo.xticks = ticks;
%                 app.UIAxes_SLTScalo.xticklabels = arrayfun(@num2str, ticks, 'UniformOutput', 0);
            end

            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)
        end

        % Value changed function: SLT_NormalizeButton
        function SLT_NormalizeButtonValueChanged(app, event)
            % Progress Bar
            wbar = waitbar(0.25, 'Please wait, Updating Plot...');
            pause(0.5)

            % Make sure it isn't already normalised
            SLTnormalise(app)

            % Convert Units
            app.sltplot = SLTunitconvert(app, app.SLT_units, app.swlet);

            % Update progress bar
            waitbar(0.75, wbar, 'Please wait, Updating Plot...');
            pause(0.5)         

            % Redraw SLT plot
            redrawSLTplot(app, app.SLT_f_vec, app.working_t_vec, app.sltplot)

            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)
        end

        % Selection changed function: SLT_SigQuantityUnitsButtonGroup
        function SLT_SigQuantityUnitsButtonGroupSelectionChanged(app, event)
            app.SLT_units = app.SLT_SigQuantityUnitsButtonGroup.SelectedObject.Text;

            % Progress Bar
            wbar = waitbar(0.25, 'Please wait, Updating Plot...');
            pause(0.5)

            % Convert Units
            if app.SLT_normed_flag == 0
                app.sltplot = SLTunitconvert(app, app.SLT_units, app.swlet);
            elseif app.SLT_normed_flag == 1
                app.sltplot = SLTunitconvert(app, app.SLT_units, app.sltplot);
            end

            % Update progress bar
            waitbar(0.75, wbar, 'Please wait, Updating Plot...');
            pause(0.5)

            % Redraw SLT plot
            redrawSLTplot(app, app.SLT_f_vec, app.working_t_vec, app.sltplot)
            
            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)
        end

        % Button pushed function: SLT_ExportPlotButton
        function SLT_ExportPlotButtonPushed(app, event)
            savepath = uigetdir(app.path, 'Select Save Directory');
            savename_scalo = ['Superlet_Scalogram_', app.file(1:end-4), '.png'];
            exportgraphics(app.UIAxes_SLTScalo, fullfile(savepath, savename_scalo), ContentType='image')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PreprocessingSettingsPanel = uipanel(app.PreProcessingTab);
            app.PreprocessingSettingsPanel.Title = 'Pre-processing Settings';
            app.TrimPanel = uipanel(app.PreprocessingSettingsPanel);
            app.TrimSignalButton = uibutton(app.TrimPanel, 'push');
            app.TrimSignalButton.ButtonPushedFcn = createCallbackFcn(app, @TrimSignalButtonPushed, true);
            app.TrimSignalButton.Text = 'Trim Signal';
            app.StartTimesEditFieldLabel = uilabel(app.TrimPanel);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.TrimPanel, 'numeric');
            app.StartTimesEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.TrimPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.TrimPanel, 'numeric');
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.ResampPanel = uipanel(app.PreprocessingSettingsPanel);
            app.SampleRateHzEditFieldLabel = uilabel(app.ResampPanel);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.ResampPanel, 'numeric');
            app.SampleRateHzEditField.ValueDisplayFormat = '%.1f';
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanged, true);
            app.UpperFreqLimitHzEditFieldLabel = uilabel(app.ResampPanel);
            app.UpperFreqLimitHzEditFieldLabel.Text = 'Upper Freq. Limit (Hz)';
            app.UpperFreqLimitHzEditField = uieditfield(app.ResampPanel, 'numeric');
            app.UpperFreqLimitHzEditField.Editable = 'off';
            app.ResampleButton = uibutton(app.ResampPanel, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.NormPanel = uipanel(app.PreprocessingSettingsPanel);
            app.preproc_NormalizeButton = uibutton(app.NormPanel, 'push');
            app.preproc_NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @preproc_NormalizeButtonPushed, true);
            app.preproc_NormalizeButton.Text = 'Normalize';
            app.MagRespAxisScalingPanel = uipanel(app.PreprocessingSettingsPanel);
            app.FrequencyAxisScaleSwitchLabel = uilabel(app.MagRespAxisScalingPanel);
            app.FrequencyAxisScaleSwitchLabel.Text = 'Frequency Axis Scale';
            app.preproc_FAxisScaleSwitch = uiswitch(app.MagRespAxisScalingPanel, 'slider');
            app.preproc_FAxisScaleSwitch.Items = {'Lin', 'Log'};
            app.preproc_FAxisScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @preproc_FAxisScaleSwitchValueChanged, true);
            app.preproc_FAxisScaleSwitch.Value = 'Lin';
            app.MagnitudeUnitsSwitchLabel = uilabel(app.MagRespAxisScalingPanel);
            app.MagnitudeUnitsSwitchLabel.Text = 'Magnitude Units';
            app.MagnitudeUnitsSwitch = uiswitch(app.MagRespAxisScalingPanel, 'slider');
            app.MagnitudeUnitsSwitch.Items = {'Linear', 'Log (dB)'};
            app.MagnitudeUnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @MagnitudeUnitsSwitchValueChanged, true);
            app.MagnitudeUnitsSwitch.Value = 'Linear';
            app.Panel = uipanel(app.PreprocessingSettingsPanel);
            app.ResetAllButton = uibutton(app.Panel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.Preproc_ExportPlotsButton = uibutton(app.Panel, 'push');
            app.Preproc_ExportPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @Preproc_ExportPlotsButtonPushed, true);
            app.Preproc_ExportPlotsButton.Text = 'Export Plots';
            app.LoadFilePanel = uipanel(app.PreProcessingTab);
            app.LoadAudioFileButton = uibutton(app.LoadFilePanel, 'push');
            app.LoadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioFileButtonPushed, true);
            app.LoadAudioFileButton.Text = 'Load Audio File';
            app.Panel_timesig = uipanel(app.PreProcessingTab);
            app.Panel_timesig.Title = 'Signal Plot';
            app.UIAxes_timesig = uiaxes(app.Panel_timesig);
            title(app.UIAxes_timesig, 'Time Domain Waveform')
            xlabel(app.UIAxes_timesig, 'Time (seconds)')
            ylabel(app.UIAxes_timesig, 'Amplitude (Arbitrary)')
            zlabel(app.UIAxes_timesig, 'Z')
            app.UIAxes_freqsig = uiaxes(app.Panel_timesig);
            title(app.UIAxes_freqsig, 'Magnitude Spectrum')
            xlabel(app.UIAxes_freqsig, 'Frequency (Hz)')
            ylabel(app.UIAxes_freqsig, 'Magnitude (arbitrary)')
            zlabel(app.UIAxes_freqsig, 'Z')
            app.SuperletScalogramTab = uitab(app.TabGroup);
            app.SuperletScalogramTab.Title = 'Superlet Scalogram';
            app.SuperletScalogramTab.ButtonDownFcn = createCallbackFcn(app, @SuperletScalogramTabButtonDown, true);
            app.TimeFrequencyAnalysisSettingsPanel_3 = uipanel(app.SuperletScalogramTab);
            app.TimeFrequencyAnalysisSettingsPanel_3.Title = 'Time-Frequency Analysis Settings';
            app.AlgorithmSettingsPanel = uipanel(app.TimeFrequencyAnalysisSettingsPanel_3);
            app.AlgorithmSettingsPanel.Title = 'Algorithm Settings';
            app.LowerSuperresolutionorderEditFieldLabel = uilabel(app.AlgorithmSettingsPanel);
            app.LowerSuperresolutionorderEditFieldLabel.Text = 'Lower Superresolution order ';
            app.Lower_order_EditField = uieditfield(app.AlgorithmSettingsPanel, 'numeric');
            app.Lower_order_EditField.Limits = [1 100];
            app.Lower_order_EditField.ValueChangedFcn = createCallbackFcn(app, @Lower_order_EditFieldValueChanged, true);
            app.Lower_order_EditField.Value = 10;
            app.UpperSuperresolutionorderEditFieldLabel = uilabel(app.AlgorithmSettingsPanel);
            app.UpperSuperresolutionorderEditFieldLabel.Text = 'Upper Superresolution order ';
            app.Upper_order_EditField = uieditfield(app.AlgorithmSettingsPanel, 'numeric');
            app.Upper_order_EditField.Limits = [1 100];
            app.Upper_order_EditField.ValueChangedFcn = createCallbackFcn(app, @Upper_order_EditFieldValueChanged, true);
            app.Upper_order_EditField.Value = 10;
            app.SuperresolutionModeSwitchLabel = uilabel(app.AlgorithmSettingsPanel);
            app.SuperresolutionModeSwitchLabel.Text = 'Superresolution Mode';
            app.SuperresolutionModeSwitch = uiswitch(app.AlgorithmSettingsPanel, 'slider');
            app.SuperresolutionModeSwitch.Items = {'Additive', 'Multiplicative'};
            app.SuperresolutionModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SuperresolutionModeSwitchValueChanged, true);
            app.SuperresolutionModeSwitch.Value = 'Multiplicative';
            app.LowestFrequencyOfInterestHzSpinnerLabel = uilabel(app.AlgorithmSettingsPanel);
            app.LowestFrequencyOfInterestHzSpinnerLabel.Text = 'Lowest Frequency Of Interest (Hz) ';
            app.LowestFSpinner = uispinner(app.AlgorithmSettingsPanel);
            app.LowestFSpinner.ValueChangedFcn = createCallbackFcn(app, @LowestFSpinnerValueChanged, true);
            app.HighestFrequencyOfInterestHzSpinnerLabel = uilabel(app.AlgorithmSettingsPanel);
            app.HighestFrequencyOfInterestHzSpinnerLabel.Text = 'Highest Frequency Of Interest (Hz) ';
            app.HighestFSpinner = uispinner(app.AlgorithmSettingsPanel);
            app.HighestFSpinner.ValueChangedFcn = createCallbackFcn(app, @HighestFSpinnerValueChanged, true);
            app.FrequencyResolutionHzSpinnerLabel = uilabel(app.AlgorithmSettingsPanel);
            app.FrequencyResolutionHzSpinnerLabel.Text = 'Frequency Resolution (Hz) ';
            app.fresSpinner = uispinner(app.AlgorithmSettingsPanel);
            app.fresSpinner.ValueChangedFcn = createCallbackFcn(app, @fresSpinnerValueChanged, true);
            app.InitialNumberofSuperletCyclesSpinnerLabel = uilabel(app.AlgorithmSettingsPanel);
            app.InitialNumberofSuperletCyclesSpinnerLabel.Text = 'Initial Number of Superlet Cycles';
            app.CyclesSpinner = uispinner(app.AlgorithmSettingsPanel);
            app.CyclesSpinner.ValueChangedFcn = createCallbackFcn(app, @CyclesSpinnerValueChanged, true);
            app.PlotScalogramButton = uibutton(app.AlgorithmSettingsPanel, 'push');
            app.PlotScalogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScalogramButtonPushed, true);
            app.PlotScalogramButton.Text = 'Plot Scalogram';
            app.PlotSettingsPanel = uipanel(app.TimeFrequencyAnalysisSettingsPanel_3);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.SLT_ExportPlotButton = uibutton(app.PlotSettingsPanel, 'push');
            app.SLT_ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SLT_ExportPlotButtonPushed, true);
            app.SLT_ExportPlotButton.Text = 'Export Plot';
            app.FrequencyAxisScalingSwitchLabel = uilabel(app.PlotSettingsPanel);
            app.FrequencyAxisScalingSwitchLabel.Text = 'Frequency Axis Scaling';
            app.SLT_FAxisScalingSwitch = uiswitch(app.PlotSettingsPanel, 'slider');
            app.SLT_FAxisScalingSwitch.Items = {'Linear', 'Logarithmic'};
            app.SLT_FAxisScalingSwitch.ValueChangedFcn = createCallbackFcn(app, @SLT_FAxisScalingSwitchValueChanged, true);
            app.SLT_FAxisScalingSwitch.Value = 'Linear';
            app.SLT_NormalizeButton = uibutton(app.PlotSettingsPanel, 'state');
            app.SLT_NormalizeButton.ValueChangedFcn = createCallbackFcn(app, @SLT_NormalizeButtonValueChanged, true);
            app.SLT_NormalizeButton.Text = 'Normalize';
            app.SLT_SigQuantityUnitsButtonGroup = uibuttongroup(app.PlotSettingsPanel);
            app.SLT_SigQuantityUnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLT_SigQuantityUnitsButtonGroupSelectionChanged, true);
            app.SLT_SigQuantityUnitsButtonGroup.Title = 'Signal Quantity Units';
            app.MagnitudearbButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.MagnitudearbButton.Text = 'Magnitude (arb)';
            app.LogMagnitudedBButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.LogMagnitudedBButton.Text = 'Log Magnitude (dB)';
            app.LogMagnitudedBButton.Value = true;
            app.PowerWButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.PowerWButton.Text = 'Power (W)';
            app.LogPowerdBWButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.LogPowerdBWButton.Text = 'Log Power (dBW)';
            app.TimeFrequencyDomainSignalPlotPanel_3 = uipanel(app.SuperletScalogramTab);
            app.TimeFrequencyDomainSignalPlotPanel_3.Title = 'Time-Frequency Domain Signal Plot ';
            app.UIAxes_SLTScalo = uiaxes(app.TimeFrequencyDomainSignalPlotPanel_3);
            title(app.UIAxes_SLTScalo, 'Superlet Scalogram')
            xlabel(app.UIAxes_SLTScalo, 'Frequency (Hz)')
            ylabel(app.UIAxes_SLTScalo, 'Time (seconds)')
            zlabel(app.UIAxes_SLTScalo, 'Z')
            app.UIAxes_SLTScalo.XDir = 'reverse';
            app.UIAxes_SLTScalo.YDir = 'reverse';
            app.STFTSpectrogramTab = uitab(app.TabGroup);
            app.STFTSpectrogramTab.Title = 'STFT Spectrogram';
            app.TimeFrequencyAnalysisSettingsPanel = uipanel(app.STFTSpectrogramTab);
            app.TimeFrequencyAnalysisSettingsPanel.Title = 'Time-Frequency Analysis Settings';
            app.TimeFrequencyDomainSignalPlotPanel = uipanel(app.STFTSpectrogramTab);
            app.TimeFrequencyDomainSignalPlotPanel.Title = 'Time-Frequency Domain Signal Plot ';
            app.UIAxes_2 = uiaxes(app.TimeFrequencyDomainSignalPlotPanel);
            title(app.UIAxes_2, 'Time Domain Signal')
            xlabel(app.UIAxes_2, 'Time (seconds)')
            ylabel(app.UIAxes_2, 'Amplitude (arbitatry)')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CODA_TF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end